classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        TabGroup           matlab.ui.container.TabGroup
        Tab                matlab.ui.container.Tab
        EditField          matlab.ui.control.EditField
        UITable            matlab.ui.control.Table
        Button             matlab.ui.control.Button
        Button_4           matlab.ui.control.Button
        Tab_2              matlab.ui.container.Tab
        UIAxes             matlab.ui.control.UIAxes
        UIAxes2            matlab.ui.control.UIAxes
        Panel              matlab.ui.container.Panel
        Panel_2            matlab.ui.container.Panel
        EditField_2Label   matlab.ui.control.Label
        EditField_2        matlab.ui.control.NumericEditField
        Panel_3            matlab.ui.container.Panel
        DropDownLabel      matlab.ui.control.Label
        DropDown           matlab.ui.control.DropDown
        dEditFieldLabel    matlab.ui.control.Label
        dEditField         matlab.ui.control.NumericEditField
        gEditFieldLabel    matlab.ui.control.Label
        gEditField         matlab.ui.control.NumericEditField
        rEditFieldLabel    matlab.ui.control.Label
        rEditField         matlab.ui.control.NumericEditField
        cEditFieldLabel    matlab.ui.control.Label
        cEditField         matlab.ui.control.NumericEditField
        Panel_4            matlab.ui.container.Panel
        DropDown_2Label    matlab.ui.control.Label
        DropDown_2         matlab.ui.control.DropDown
        EditField_3Label   matlab.ui.control.Label
        EditField_3        matlab.ui.control.NumericEditField
        Button_5           matlab.ui.control.Button
        Button_7           matlab.ui.control.Button
        DropDown_3Label    matlab.ui.control.Label
        DropDown_3         matlab.ui.control.DropDown
        EditField_4Label   matlab.ui.control.Label
        EditField_4        matlab.ui.control.NumericEditField
        CheckBox           matlab.ui.control.CheckBox
        TextAreaLabel      matlab.ui.control.Label
        TextArea           matlab.ui.control.TextArea
        Tab_6              matlab.ui.container.Tab
        UIAxes_2           matlab.ui.control.UIAxes
        UIAxes2_2          matlab.ui.control.UIAxes
        Panel_5            matlab.ui.container.Panel
        Panel_6            matlab.ui.container.Panel
        DropDown_4Label    matlab.ui.control.Label
        DropDown_4         matlab.ui.control.DropDown
        EditField_9Label   matlab.ui.control.Label
        EditField_9        matlab.ui.control.NumericEditField
        EditField_10Label  matlab.ui.control.Label
        EditField_10       matlab.ui.control.NumericEditField
        CEditFieldLabel    matlab.ui.control.Label
        CEditField         matlab.ui.control.NumericEditField
        EditField2         matlab.ui.control.NumericEditField
        Label              matlab.ui.control.Label
        gEditField_2Label  matlab.ui.control.Label
        gEditField_2       matlab.ui.control.NumericEditField
        EditField2_2       matlab.ui.control.NumericEditField
        Label_2            matlab.ui.control.Label
        Button_8           matlab.ui.control.Button
        Button_10          matlab.ui.control.Button
        EditField_5Label   matlab.ui.control.Label
        EditField_5        matlab.ui.control.NumericEditField
        CheckBox_2         matlab.ui.control.CheckBox
        EditField_6Label   matlab.ui.control.Label
        EditField_6        matlab.ui.control.EditField
        EditField_7Label   matlab.ui.control.Label
        EditField_7        matlab.ui.control.NumericEditField
        EditField_8Label   matlab.ui.control.Label
        EditField_8        matlab.ui.control.EditField
        Panel_7            matlab.ui.container.Panel
        CheckBox_3         matlab.ui.control.CheckBox
        DropDown_5Label    matlab.ui.control.Label
        DropDown_5         matlab.ui.control.DropDown
        TextArea_2Label    matlab.ui.control.Label
        TextArea_2         matlab.ui.control.TextArea
        Tab_4              matlab.ui.container.Tab
        UIAxes2_3          matlab.ui.control.UIAxes
        Panel_8            matlab.ui.container.Panel
        Panel_9            matlab.ui.container.Panel
        EditField_15Label  matlab.ui.control.Label
        EditField_15       matlab.ui.control.NumericEditField
        EditField_16Label  matlab.ui.control.Label
        EditField_16       matlab.ui.control.NumericEditField
        CEditField_2Label  matlab.ui.control.Label
        CEditField_2       matlab.ui.control.NumericEditField
        EditField2_3       matlab.ui.control.NumericEditField
        Label_3            matlab.ui.control.Label
        gEditField_3Label  matlab.ui.control.Label
        gEditField_3       matlab.ui.control.NumericEditField
        EditField2_4       matlab.ui.control.NumericEditField
        Label_4            matlab.ui.control.Label
        EditField_17Label  matlab.ui.control.Label
        EditField_17       matlab.ui.control.EditField
        Button_11          matlab.ui.control.Button
        Button_12          matlab.ui.control.Button
        EditField_11Label  matlab.ui.control.Label
        EditField_11       matlab.ui.control.NumericEditField
        CheckBox_4         matlab.ui.control.CheckBox
        EditField_12Label  matlab.ui.control.Label
        EditField_12       matlab.ui.control.EditField
        EditField_13Label  matlab.ui.control.Label
        EditField_13       matlab.ui.control.NumericEditField
        EditField_14Label  matlab.ui.control.Label
        EditField_14       matlab.ui.control.EditField
        Panel_10           matlab.ui.container.Panel
        CheckBox_5         matlab.ui.control.CheckBox
        DropDown_6Label    matlab.ui.control.Label
        DropDown_6         matlab.ui.control.DropDown
        Panel_14           matlab.ui.container.Panel
        Label_5            matlab.ui.control.Label
        DropDown_7         matlab.ui.control.DropDown
        TextArea_3Label    matlab.ui.control.Label
        TextArea_3         matlab.ui.control.TextArea
        UIAxes_3           matlab.ui.control.UIAxes
        Tab_7              matlab.ui.container.Tab
        UITable_2          matlab.ui.control.Table
        Button_16          matlab.ui.control.Button
        Panel_11           matlab.ui.container.Panel
        TextArea_4         matlab.ui.control.TextArea
        Button_17          matlab.ui.control.StateButton
        TextArea_5Label_2  matlab.ui.control.Label
        Panel_12           matlab.ui.container.Panel
        TextArea_5Label    matlab.ui.control.Label
        TextArea_5         matlab.ui.control.TextArea
        Button_18          matlab.ui.control.StateButton
        Panel_13           matlab.ui.container.Panel
        TextArea_6Label    matlab.ui.control.Label
        TextArea_6         matlab.ui.control.TextArea
        Button_19          matlab.ui.control.StateButton
        Button_20          matlab.ui.control.Button
        EditField_18       matlab.ui.control.EditField
    end

    
    properties (Access = private)
        X2;
        model_1;
        model_2;
        model_3;
        model_4;
        model_5;
        model_6;
        fault_data; 
        normal;
        fault_part;
        fault_word_1;
        fault_word_2;       
        fault_word_3;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            [filename,pathname] = uigetfile({'*.xlsx';'*.xls';'*.txt'});            
            file=strcat(pathname,filename);
            app.UITable.Data=readtable(file);
            app.X2=xlsread(file);
            data=app.X2;
            save ('X2','data');
            return
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            msgbox(['该软件主要实现了航空发动机气路智能诊断的测试功能，主界面包含训练数据导入模块、故障判断模型模块、故障定位模型模块、故障识别模型模块和故障诊断模块。' ...
                '1.训练数据导入模块实现航空发动机气路数据的导入，气路数据主要参数有风扇、压气机和高低压涡轮四个部件的气路数据及其故障标签；' ...
                '2.故障判断模型模块训练了航空发动机判断的模型，实现的方法有随机森林、支持向量机和极限学习机，且算法中参数可自行设置；' ...
                '3.故障定位模型模块训练了航空发动机部件故障的定位的模型，使用方法为支持向量机，可使用粒子群算法、灰狼算法和改进的灰狼算法对支持向量机的参数进行智能寻优，同样算法中参数可自行设置；' ...
                '4.故障识别模型模块训练了航空发动机部件故障类别的模型，使用方法为IGWO-KELM，实现故障定位需要大量的故障数据作为支撑，所以运行时间较长，用户需耐心等待；' ...
                '5.智能诊断窗口是实现航空发动机故障判断、定位和识别的综合诊断，利用上述保留的模型实现不同需求的故障诊断。'] ,'关于','help');
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            T=120;
            X=app.X2(1:T,:);
            X1=[X;app.X2(T+1:((1+1)*T),:)];
            t=app.EditField_4.Value;
            A= randperm(120);
            train_data = X1(A(1:120*t),:);
            test_data= X1(A(120*t+1:end),:);% 选定训练集和测试集
            A=A+120;
            train_data=[train_data;X1(A(1:120*t),:)];
            test_data=[test_data;X1(A(120*t+1:end),:)];
            test_data_label_1=test_data(:,8);
            train_data_label_1=train_data(:,8);
            test_data=test_data(:,1:7);
            train_data=train_data(:,1:7);
            %% 数据预处理
            % 数据预处理,将训练集和测试集归一化到[0,1]区间
            [mtrain,~] = size(train_data);
            [mtest,~] = size(test_data);
            dataset = [train_data;test_data];
            % mapminmax为MATLAB自带的归一化函数
            if  app.CheckBox.Value==true
                [dataset_scale,~] = mapminmax(dataset',0,1);
                dataset_scale = dataset_scale';
                train_data = dataset_scale(1:mtrain,:);
                test_data= dataset_scale( (mtrain+1):(mtrain+mtest),: );
            elseif app.CheckBox.Value==false
                train_data = dataset(1:mtrain,:);
                test_data= dataset( (mtrain+1):(mtrain+mtest),: );
            else
                return
            end
            %% 数据预处理
            if  app.DropDown_3.Value=="随机森林"
                model=classRF_train(train_data,train_data_label_1,app.EditField.Value);
                [T_sim_train_1,~] = classRF_predict(train_data,model);
                [T_sim_predict_1,~] = classRF_predict(test_data,model);
                acc_train=length(find(train_data_label_1==T_sim_train_1))/mtrain*100;
                acc_predict=length(find(test_data_label_1==T_sim_predict_1))/mtest*100;
            elseif app.DropDown_3.Value=="支持向量机"
                if app.DropDown.Value=="多项式核函数"
                    d=app.dEditField.Value;g=app.gEditField.Value;
                    r=app.rEditField.Value;c=app.cEditField.Value;
                    svm=['-t 1', '-d',num2str(d),'-g',num2str(g),'-r',num2str(r),'-c',num2str(c)];
                    model = svmtrain(train_data_label_1, train_data,svm);
                    [T_sim_train_1,acc_1] = svmpredict(train_data_label_1,train_data,model);
                    [T_sim_predict_1,acc_2] = svmpredict(test_data_label_1,test_data,model);
                    acc_train=acc_1(1);
                    acc_predict=acc_2(1);
                elseif  app.DropDown.Value=="高斯核函数"
                    g=app.gEditField.Value;c=app.cEditField.Value;
                    svm=['-t 2','-g',num2str(g),'-c',num2str(c)];
                    model = svmtrain(train_data_label_1, train_data,svm);
                    [T_sim_train_1,acc_1] = svmpredict(train_data_label_1,train_data,model);
                    [T_sim_predict_1,acc_2] = svmpredict(test_data_label_1,test_data,model);
                    acc_train=acc_1(1);
                    acc_predict=acc_2(1);
                elseif app.DropDown.Value=="两层感知器核函数"
                    g=app.gEditField.Value;r=app.rEditField.Value;c=app.cEditField.Value;
                    svm=['-t 3','-g',num2str(g),'-r',num2str(r),'-c',num2str(c)];
                    model = svmtrain(train_data_label_1, train_data,svm);
                    [T_sim_train_1,acc_1] = svmpredict(train_data_label_1,train_data,model);
                    [T_sim_predict_1,acc_2] = svmpredict(test_data_label_1,test_data,model);
                    acc_train=acc_1(1);
                    acc_predict=acc_2(1);
                else
                    return
                end
            elseif app.DropDown_3.Value=="极限学习机"
                train_data_label_1=train_data_label_1+1;
                test_data_label_1=test_data_label_1+1;
                n3=app.EditField_3.Value;
                
                if app.DropDown_2.Value=="sigmoid"
                    model = elmtrain(train_data',train_data_label_1',n3,'sig',1);
                    T_sim_1=elmpredict(train_data',model);
                    T_sim_2=elmpredict(test_data',model);
                    T_sim_train_1=(T_sim_1-1)';
                    T_sim_predict_1=(T_sim_2-1)';
                    train_data_label_1=train_data_label_1-1;
                    test_data_label_1=test_data_label_1-1;
                    acc_train=length(find(train_data_label_1==T_sim_train_1))/mtrain*100;
                    acc_predict=length(find(test_data_label_1==T_sim_predict_1))/mtest*100;
                elseif app.DropDown_2.Value=="sin"
                    model = elmtrain(train_data',train_data_label_1',n3,'sin',1);
                    T_sim_1=elmpredict(train_data',model);
                    T_sim_2=elmpredict(test_data',model);
                    T_sim_train_1=(T_sim_1-1)';
                    T_sim_predict_1=(T_sim_2-1)';
                    train_data_label_1=train_data_label_1-1;
                    test_data_label_1=test_data_label_1-1;
                    acc_train=length(find(train_data_label_1==T_sim_train_1))/mtrain*100;
                    acc_predict=length(find(test_data_label_1==T_sim_predict_1))/mtest*100;
                elseif app.DropDown_2.Value=="hardlim"
                    model = elmtrain(train_data',train_data_label_1',n3,'hardlim',1);
                    T_sim_1=elmpredict(train_data',model);
                    T_sim_2=elmpredict(test_data',model);
                    T_sim_train_1=(T_sim_1-1)';
                    T_sim_predict_1=(T_sim_2-1)';
                    train_data_label_1=train_data_label_1-1;
                    test_data_label_1=test_data_label_1-1;
                    acc_train=length(find(train_data_label_1==T_sim_train_1))/mtrain*100;
                    acc_predict=length(find(test_data_label_1==T_sim_predict_1))/mtest*100;
                else
                    return
                end
            else
                return
            end
            model.AL=app.DropDown_3.Value;
            app.model_1=model;
            plot(app.UIAxes,1:mtrain,train_data_label_1,'o',1:mtrain,T_sim_train_1','r*-');
            plot(app.UIAxes2,1:mtest,test_data_label_1,'o',1:mtest,T_sim_predict_1,'r*-');
            str1='%s航空发动机故障诊断训练集准确率:%g%s;测试集准确率:%g%s';
            app.TextArea.Value=sprintf(str1,app.DropDown_3.Value,acc_train,'%',acc_predict,'%'); %
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            model=app.model_1;
            save('fault_modle_1','model');
        end

        % Button pushed function: Button_10
        function Button_10Pushed(app, event)
            N=480;
            B=randperm(N);
            T=120+B(randperm(120));
            X3=app.X2(T,:);
            for i=1:3
                T=T+480;
                X3=[X3;app.X2(T,:)];
            end
           t=app.EditField_5.Value; v=app.EditField_7.Value; 
           if app.CheckBox_3.Value==true
               X=X3(:,1:7);
               Z=X3(:,9);
               switch app.DropDown_5.Value
                   case '添加1%' 
                       ii=1;
                   case '添加2%' 
                       ii=2;
                   case '添加3%' 
                       ii=3;
                   case '添加4%' 
                       ii=4;
                   otherwise  
                       ii=5;
               end 
                   epsilon=ii/100;
                   noise=randn(size(X)).*(epsilon*std(X));
                   Y=X+noise;
              X3=[Y,Z]; 
           elseif app.CheckBox_3.Value==false
               X=X3(:,1:7);
               Z=X3(:,9);
               X3=[X,Z];
           else
               return 
           end
           maxgen=app.EditField_9.Value;sizepop=app.EditField_10.Value;
           popcmin=app.CEditField.Value;popcmax=app.EditField2.Value;
           popgmin=app.gEditField_2.Value;popgmax=app.EditField2_2.Value; 
           T1=120;% 单类类别数
           A= randperm(T1);
           train_data = X3(A(1:T1*t),:);
           test_data= X3(A(T1*t+1:end),:);% 选定训练集和测试集
           %随机选取训练集和测试集
           for i=1:3
               A=A+T1;
               train_data=[train_data;X3(A(1:T1*t),:)];
               test_data=[test_data;X3(A(T1*t+1:end),:)];
           end
           test_data_label_2=test_data(:,end);
           train_data_label=train_data(:,end);
           test_data=test_data(:,1:(end-1));
           train_data=train_data(:,1:(end-1));
           [mtrain,~] = size(train_data);
           [mtest,~] = size(test_data);
           dataset = [train_data;test_data];
            % mapminmax为MATLAB自带的归一化函数
            if  app.CheckBox_2.Value==true
                [dataset_scale,~] = mapminmax(dataset',0,1);
                dataset_scale = dataset_scale';
                train_data = dataset_scale(1:mtrain,:);
                test_data= dataset_scale( (mtrain+1):(mtrain+mtest),: ); 
            elseif app.CheckBox_2.Value==false
                train_data = dataset(1:mtrain,:);
                test_data= dataset( (mtrain+1):(mtrain+mtest),: );
            else
                return
            end
            if  app.DropDown_4.Value=="PSO"
                str=struct('c1',1.5,'c2',1.7,'maxgen',maxgen,'sizepop',sizepop, ...
                    'k',0.6,'wV',1,'wP',1,'v',v, ...
                    'popcmax',popcmax,'popcmin',popcmin,'popgmax',popgmax,'popgmin',popgmin);
                [bestc,bestg,fit_gen] = PSO(train_data_label,train_data,str);
                FIT=-fit_gen;
                cmd = ['-c ',num2str(bestc),' -g ',num2str(bestg)];
                model = svmtrain(train_data_label,train_data ,cmd);
                [T_sim_predict_2,~] = svmpredict(test_data_label_2,test_data ,model);
            elseif app.DropDown_4.Value=="GWO"
                str=struct('maxgen',maxgen,'sizepop',sizepop,'v',v, ...
                    'popcmax',popcmax,'popcmin',popcmin,'popgmax',popgmax,'popgmin',popgmin);
                [bestc,bestg,fit_gen] = GWO(train_data_label,train_data,str);
                 FIT=-fit_gen;
                cmd = ['-c ',num2str(bestc),' -g ',num2str(bestg)];
                model = svmtrain(train_data_label,train_data ,cmd);
                [T_sim_predict_2,~] = svmpredict(test_data_label_2,test_data ,model);
            elseif app.DropDown_4.Value=="IGWO"
                str=struct('maxgen',maxgen,'sizepop',sizepop,'v',v, ...
                    'popcmax',popcmax,'popcmin',popcmin,'popgmax',popgmax,'popgmin',popgmin);
                [bestc,bestg,fit_gen] =IGWO(train_data_label,train_data,str);
                 FIT=-fit_gen;
                cmd = ['-c ',num2str(bestc),' -g ',num2str(bestg)];
                model = svmtrain(train_data_label,train_data ,cmd);
                [T_sim_predict_2,~] = svmpredict(test_data_label_2,test_data ,model);
            else
                return
            end
            app.model_2=model;
            plot(app.UIAxes_2,1:maxgen,FIT,'.-');
            plot(app.UIAxes2_2,1:mtest,test_data_label_2,'o',1:mtest,T_sim_predict_2,'r*-');
            number_sim_all=length(find(T_sim_predict_2 == test_data_label_2));
            accuracy = length(find(T_sim_predict_2 == test_data_label_2))/length(test_data_label_2)*100;
            for i=1:4
                number_M(i)= length(find(test_data_label_2 ==i));
                number_M_sim(i)=length(find(T_sim_predict_2 ==i &test_data_label_2 ==i));
                acc(i)=(number_M_sim(i)/number_M(i))*100;
            end
            str1='%s-SVM模型航空发动机总部件诊断准确率：%g%s,风扇部件故障准确率：%g%s,压气机部件准确率：%g%s,高压涡轮部件故障准确率：%g%s,低压涡轮部件故障准确率：%g%s';
            app.TextArea_2.Value=sprintf(str1,app.DropDown_4.Value,accuracy,'%',acc(1),'%',acc(2),'%',acc(3),'%',acc(4),'%');
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            model=app.model_2;
            save('fault_modle_2','model'); 
        end

        % Button pushed function: Button_12
        function Button_12Pushed(app, event)

            N=120;
            x4=cell(1,4);
            for i=1:4
                x4{i}=app.X2((N+1+(i-1)*480):(N+i*480),:);
            end
            switch app.DropDown_7.Value
                case '风扇'
                    X4=x4{1};
                case'压气机'
                    X4=x4{2};
                case'高压涡轮'
                    X4=x4{3};
                case'低压涡轮'
                    X4=x4{4};  
            end
        t=app.EditField_11.Value;v=app.EditField_13.Value;
            if app.CheckBox_5.Value==true
               X=X4(:,1:7);
               Z=X4(:,end);
               switch app.DropDown_6.Value
                   case '添加1%' 
                       ii=1;
                   case '添加2%' 
                       ii=2;
                   case '添加3%' 
                       ii=3;
                   case '添加4%' 
                       ii=4;
                   otherwise  
                       ii=5;
               end 
                   epsilon=ii/100;
                   noise=randn(size(X)).*(epsilon*std(X));
                   Y=X+noise;
              X4=[Y,Z]; 
           elseif app.CheckBox_5.Value==false
                X=X4(:,1:7);
               Z=X4(:,end);
               X4=[X,Z];
           else
               return 
            end
           maxgen=app.EditField_15.Value;sizepop=app.EditField_16.Value;
           popcmin=app.CEditField_2.Value;popcmax=app.EditField2_3.Value;
           popgmin=app.gEditField_3.Value;popgmax=app.EditField2_4.Value; 
           T=120;% 单类类别数
           A= randperm(T);
           train_data = X4(A(1:T*t),:);
           test_data= X4(A((T*t+1):end),:);
%            随机选取训练集和测试集
           for i=1:3
               A=A+T;
               train_data=[train_data;X4(A(1:T*t),:)];
               test_data=[test_data;X4(A(T*t+1:end),:)];
           end
           test_data_label_3=test_data(:,end);
           train_data_label=train_data(:,end);
           test_data=test_data(:,1:(end-1));
           train_data=train_data(:,1:(end-1));
           [mtrain,~] = size(train_data);
           [mtest,~] = size(test_data);
           dataset = [train_data;test_data];
            if  app.CheckBox_4.Value==true
                [dataset,~] = mapminmax(dataset',0,1);
                dataset = dataset';
                train_data = dataset(1:mtrain,:);
                test_data= dataset( (mtrain+1):(mtrain+mtest),: ); 
            elseif app.CheckBox_4.Value==false
                train_data = dataset(1:mtrain,:);
                test_data= dataset( (mtrain+1):(mtrain+mtest),: );
            else
                return
            end
            str=struct('maxgen',maxgen,'sizepop',sizepop,'v',v, ...
                    'popcmax',popcmax,'popcmin',popcmin,'popgmax',popgmax,'popgmin',popgmin);
            [fit,bestc,bestg]=IGWO_KELM(train_data,train_data_label,str);
            FIT_2=-fit*100;
            RegularCoef=bestc; %正则化系数                   
            KernelArgs=bestg;%核函数参数
           model=kelmtrain(train_data,train_data_label,RegularCoef,KernelArgs);
           [T_sim_predict_3,test_data_label_3,TestingAccuracy] = kelmpredict(test_data,test_data_label_3,model);
            accuracy=TestingAccuracy*100;
            switch app.DropDown_7.Value
                case '风扇'
                    app.model_3=model;
                case '压气机'
                    app.model_4=model;
                case '高压涡轮'
                    app.model_5=model;
                case '低压涡轮'
                    app.model_6=model;      
            end
            app.model_3=model;
            plot(app.UIAxes_3,1:maxgen,FIT_2,'.-')
            plot(app.UIAxes2_3,1:mtest,test_data_label_3,'o',1:mtest,T_sim_predict_3,'r*-');
            for i=1:4
                number_M(i)= length(find(test_data_label_3 ==i));
                number_M_sim(i)=length(find(T_sim_predict_3==i &test_data_label_3==i));
                acc(i)=number_M_sim(i)/number_M(i)*100;
            end 
            str=['IGWO-KELM模型',app.DropDown_7.Value,'部件总故障类别诊断准确率为：',num2str(accuracy), '%;'];
            for i=1:4
                str=[str,'故障',num2str(i),'类的诊断准确率为：',num2str(acc(i)),'%;'];
            end
             app.TextArea_3.Value=str;
        end

        % Button pushed function: Button_11
        function Button_11Pushed(app, event)
            switch app.DropDown_7.Value
                case '风扇'
                    model_1=app.model_3;
                    save('fault_modle_3','model_1');
                case '压气机'
                    model_2=app.model_4;
                    save('fault_modle_4','model_2');
                case '高压涡轮'
                    model_3=app.model_5;
                    save('fault_modle_5','model_3');
                case '低压涡轮'
                    model_4=app.model_6;
                    save('fault_modle_6','model_4');
            end
        end

        % Callback function
        function Button_13Pushed(app, event)
            delete(app.UIFigure);
            run login.mlapp;
        end

        % Value changed function: Button_17
        function Button_17ValueChanged(app, event)
            load fault_modle_1;
            str=model.AL;
            app.fault_data=mapminmax(app.fault_data',0,1)';
            [n,~]=size(app.fault_data);
            switch str
                case '随机森林'
                    [predict_data,~]= classRF_predict(app.fault_data,model);
                case '支持向量机'
                    model= rmfield(model,'AL');
                    test_data_label=randperm(n)';
                    [predict_data,~]= svmpredict(test_data_label,app.fault_data,model);
                case '极限学习机'
                    predict_data=elmpredict(app.fault_data',model);
                    predict_data=predict_data-1;
            end
            str1=[];
            app.normal=0;
            for i=1:n
                switch predict_data(i)
                    case 0
                        str1=[str1,'第',num2str(i),'组数据为：正常数据；'];
                        app.fault_data(i,:)=[];
                        app.normal=app.normal+1;
                    case 1
                        str1=[str1,'第',num2str(i),'组数据为：故障数据；'];
                end
                
            end
            app.TextArea_4.Value=str1;
            app.fault_word_1=str1;  
        end

        % Button pushed function: Button_16
        function Button_16Pushed(app, event)
            [filename,pathname] = uigetfile({'*.xlsx';'*.xls';'*.txt'});
            file=strcat(pathname,filename);
            app.UITable_2.Data=readtable(file);
            app.fault_data=xlsread(file);
        end

        % Size changed function: Panel_11
        function Panel_11SizeChanged(app, event)
 
        end

        % Value changed function: Button_18
        function Button_18ValueChanged(app, event)
            load fault_modle_2;
            [n,~]=size(app.fault_data);  
            test_data_label=randperm(n)';
            [predict_data,~]= svmpredict(test_data_label,app.fault_data,model);
            app.fault_part=predict_data;
            str1=[];
            for i=1:n
                switch predict_data(i)
                    case 1
                        str1=[str1,'第',num2str(i+app.normal),'组数据为：风扇部件故障；'];
                    case 2
                        str1=[str1,'第',num2str(i+app.normal),'组数据为：压气机部件故障；'];
                    case 3
                        str1=[str1,'第',num2str(i+app.normal),'组数据为：高压涡轮部件故障；'];
                    case 4
                        str1=[str1,'第',num2str(i+app.normal),'组数据为：低压涡轮部件故障；'];
                end
                
            end
            app.TextArea_5.Value=str1;
            app.fault_word_2=str1;  
        end

        % Value changed function: Button_19
        function Button_19ValueChanged(app, event)
            load fault_modle_3;
            load fault_modle_4;
            load fault_modle_5;
            load fault_modle_6;
            [n,~]=size(app.fault_data);
            test_data_label=randperm(1)';
            str1=[];
            for i=1:n
            switch app.fault_part(i)
                case 1
                    [predict_data,~]= kelmpredict(app.fault_data(i),test_data_label,model_1);
                    switch predict_data
                        case 1
                            str1=[str1,'第',num2str(i+app.normal),'组数据为：风扇叶片结垢；'];
                        case 2
                            str1=[str1,'第',num2str(i+app.normal),'组数据为：风扇叶片磨损；'];
                        case 3
                            str1=[str1,'第',num2str(i+app.normal),'组数据为：风扇叶片受外物损伤；'];
                        case 4
                            str1=[str1,'第',num2str(i+app.normal),'组数据为：风扇叶片腐蚀；'];
                    end
                case 2
                    [predict_data,~]= kelmpredict(app.fault_data(i),test_data_label,model_2);
                    switch predict_data
                        case 1
                            str1=[str1,'第',num2str(i+app.normal),'组数据为：压气机叶片结垢；'];
                        case 2
                            str1=[str1,'第',num2str(i+app.normal),'组数据为：压气机叶片磨损；'];
                        case 3
                            str1=[str1,'第',num2str(i+app.normal),'组数据为：压气机叶片受外物损伤；'];
                        case 4
                            str1=[str1,'第',num2str(i+app.normal),'组数据为：压气机叶片腐蚀；'];
                    end
                case 3
                    [predict_data,~]= kelmpredict(app.fault_data(i),test_data_label,model_3);
                    switch predict_data
                        case 1
                            str1=[str1,'第',num2str(i+app.normal),'组数据为；高压涡轮叶片结垢；'];
                        case 2
                            str1=[str1,'第',num2str(i+app.normal),'组数据为：高压涡轮喷嘴腐蚀；'];
                        case 3
                            str1=[str1,'第',num2str(i+app.normal),'组数据为：高压涡轮叶片磨损；'];
                        case 4
                            str1=[str1,'第',num2str(i+app.normal),'组数据为：高压涡轮叶片机械损伤；'];
                    end
                case 4
                    [predict_data,~]= kelmpredict(app.fault_data(i),test_data_label,model_4);
                    switch predict_data
                        case 1
                            str1=[str1,'第',num2str(i+app.normal),'组数据为：低压涡轮叶片结垢；'];
                        case 2
                            str1=[str1,'第',num2str(i+app.normal),'组数据为：低压涡轮喷嘴腐蚀；'];
                        case 3
                            str1=[str1,'第',num2str(i+app.normal),'组数据为：低压涡轮叶片磨损；'];
                        case 4
                            str1=[str1,'第',num2str(i+app.normal),'组数据为：低压涡轮叶片机械损伤；'];
                    end
            end
            end
            app.TextArea_6.Value=str1;
            app.fault_word_3=str1;          
        end

        % Button pushed function: Button_20
        function Button_20Pushed(app, event)
            fid=fopen('fault_word.txt','wt');
            fprintf(fid,'%s\n',app.fault_word_1);
            fprintf(fid,'%s\n',app.fault_word_2);
            fprintf(fid,'%s',app.fault_word_3);
            fclose(fid);
        end

        % Value changed function: DropDown_7
        function DropDown_7ValueChanged(app, event)
            value = app.DropDown_7.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '气路数据模块';
            app.EditField = uieditfield(app.Tab, 'text');
            app.EditField.Value = '航空发动机气路数据表';
            app.UITable = uitable(app.Tab);
            app.UITable.ColumnName = {'高压转子转速nh(rpm)'; '风扇出口压力p21(100kpa)'; '压气机出口压力p3(100kpa)'; '高压涡轮出口压力p5（100kpa）'; '低压涡轮出口压力p55'; '高压涡轮出口温度t5(K)'; '低压涡轮出口温度t55'; '故障标签'; '故障部件标签'; '故障类别标签'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = true;
            app.UITable.ColumnEditable = true;
            app.Button = uibutton(app.Tab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '导入数据';
            app.Button_4 = uibutton(app.Tab, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '关于软件';
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '航空发动机故障智能诊断模块';
            app.UIAxes = uiaxes(app.Tab_2);
            title(app.UIAxes, '训练集的诊断示意图')
            xlabel(app.UIAxes, '训练集样本')
            ylabel(app.UIAxes, '类别标签')
            app.UIAxes2 = uiaxes(app.Tab_2);
            title(app.UIAxes2, '测试集的诊断示意图')
            xlabel(app.UIAxes2, '测试集样本')
            ylabel(app.UIAxes2, '类别标签')
            app.Panel = uipanel(app.Tab_2);
            app.Panel.Tooltip = {''};
            app.Panel.Title = '航空发动机的故障诊断模型';
            app.Panel_2 = uipanel(app.Panel);
            app.Panel_2.Title = '随机森林参数设置';
            app.EditField_2Label = uilabel(app.Panel_2);
            app.EditField_2Label.Text = '决策树数量';
            app.EditField_2 = uieditfield(app.Panel_2, 'numeric');
            app.EditField_2.Value = 240;
            app.Panel_3 = uipanel(app.Panel);
            app.Panel_3.Title = '支持向量机参数设置';
            app.DropDownLabel = uilabel(app.Panel_3);
            app.DropDownLabel.Text = '核函数';
            app.DropDown = uidropdown(app.Panel_3);
            app.DropDown.Items = {'多项式核函数', '高斯核函数', '两层感知器核函数'};
            app.DropDown.Value = '多项式核函数';
            app.dEditFieldLabel = uilabel(app.Panel_3);
            app.dEditFieldLabel.Text = '参数d';
            app.dEditField = uieditfield(app.Panel_3, 'numeric');
            app.dEditField.Tooltip = {'针对多项式函数默认值为3'};
            app.dEditField.Value = 3;
            app.gEditFieldLabel = uilabel(app.Panel_3);
            app.gEditFieldLabel.Text = '参数g';
            app.gEditField = uieditfield(app.Panel_3, 'numeric');
            app.gEditField.Tooltip = {'核函数中gamma参数函数设置（三个）'};
            app.gEditField.Value = 0.14;
            app.rEditFieldLabel = uilabel(app.Panel_3);
            app.rEditFieldLabel.Text = '参数r';
            app.rEditField = uieditfield(app.Panel_3, 'numeric');
            app.rEditField.Tooltip = {'多项式核函数核两层感知机核函数的参数'};
            app.cEditFieldLabel = uilabel(app.Panel_3);
            app.cEditFieldLabel.Text = '参数c';
            app.cEditField = uieditfield(app.Panel_3, 'numeric');
            app.cEditField.Tooltip = {'正则化系数'};
            app.cEditField.Value = 1;
            app.Panel_4 = uipanel(app.Panel);
            app.Panel_4.Title = '极限学习机参数设置';
            app.DropDown_2Label = uilabel(app.Panel_4);
            app.DropDown_2Label.Text = '激活函数';
            app.DropDown_2 = uidropdown(app.Panel_4);
            app.DropDown_2.Items = {'sigmoid', 'sin', 'hardlim'};
            app.DropDown_2.Value = 'sigmoid';
            app.EditField_3Label = uilabel(app.Panel_4);
            app.EditField_3Label.Text = '隐藏层神经元个数';
            app.EditField_3 = uieditfield(app.Panel_4, 'numeric');
            app.EditField_3.Tooltip = {''};
            app.EditField_3.Value = 240;
            app.Button_5 = uibutton(app.Panel, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '保留诊断模型';
            app.Button_7 = uibutton(app.Panel, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '开始诊断';
            app.DropDown_3Label = uilabel(app.Panel);
            app.DropDown_3Label.Text = '算法';
            app.DropDown_3 = uidropdown(app.Panel);
            app.DropDown_3.Items = {'随机森林', '支持向量机', '极限学习机'};
            app.DropDown_3.Editable = 'on';
            app.DropDown_3.Tooltip = {''};
            app.DropDown_3.Value = '随机森林';
            app.EditField_4Label = uilabel(app.Panel);
            app.EditField_4Label.Text = '样本比';
            app.EditField_4 = uieditfield(app.Panel, 'numeric');
            app.EditField_4.Tooltip = {'训练样本与测试样本数量的比值'};
            app.EditField_4.Value = 0.5;
            app.CheckBox = uicheckbox(app.Panel);
            app.CheckBox.Tooltip = {'把数据缩放到[0'; '1]'};
            app.CheckBox.Text = '归一化';
            app.CheckBox.Value = true;
            app.TextAreaLabel = uilabel(app.Tab_2);
            app.TextAreaLabel.Text = '诊断结果';
            app.TextArea = uitextarea(app.Tab_2);
            app.Tab_6 = uitab(app.TabGroup);
            app.Tab_6.Title = '航空发动机气路部件故障智能诊断模块';
            app.UIAxes_2 = uiaxes(app.Tab_6);
            title(app.UIAxes_2, '智能算法寻优示意图')
            xlabel(app.UIAxes_2, '迭代次数')
            ylabel(app.UIAxes_2, '最佳适应度值(%)')
            app.UIAxes2_2 = uiaxes(app.Tab_6);
            title(app.UIAxes2_2, '测试集的诊断示意图')
            xlabel(app.UIAxes2_2, '测试集样本')
            ylabel(app.UIAxes2_2, '类别标签')
            app.Panel_5 = uipanel(app.Tab_6);
            app.Panel_5.Tooltip = {''};
            app.Panel_5.Title = '航空发动机气路部件的故障诊断模型';
            app.Panel_6 = uipanel(app.Panel_5);
            app.Panel_6.Title = '智能优化算法参数设置';
            app.DropDown_4Label = uilabel(app.Panel_6);
            app.DropDown_4Label.Text = '智能寻优算法';
            app.DropDown_4 = uidropdown(app.Panel_6);
            app.DropDown_4.Items = {'PSO', 'GWO', 'IGWO'};
            app.DropDown_4.Value = 'IGWO';
            app.EditField_9Label = uilabel(app.Panel_6);
            app.EditField_9Label.Text = '进化迭代数';
            app.EditField_9 = uieditfield(app.Panel_6, 'numeric');
            app.EditField_9.Value = 50;
            app.EditField_10Label = uilabel(app.Panel_6);
            app.EditField_10Label.Text = '种群数量';
            app.EditField_10 = uieditfield(app.Panel_6, 'numeric');
            app.EditField_10.Value = 10;
            app.CEditFieldLabel = uilabel(app.Panel_6);
            app.CEditFieldLabel.Text = '参数C的范围';
            app.CEditField = uieditfield(app.Panel_6, 'numeric');
            app.CEditField.Value = 0.01;
            app.EditField2 = uieditfield(app.Panel_6, 'numeric');
            app.EditField2.Value = 100;
            app.Label = uilabel(app.Panel_6);
            app.Label.Text = '—';
            app.gEditField_2Label = uilabel(app.Panel_6);
            app.gEditField_2Label.Text = '参数g的范围';
            app.gEditField_2 = uieditfield(app.Panel_6, 'numeric');
            app.gEditField_2.Value = 0.01;
            app.EditField2_2 = uieditfield(app.Panel_6, 'numeric');
            app.EditField2_2.Value = 100;
            app.Label_2 = uilabel(app.Panel_6);
            app.Label_2.Text = '—';
            app.Button_8 = uibutton(app.Panel_5, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '保留诊断模型';
            app.Button_10 = uibutton(app.Panel_5, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @Button_10Pushed, true);
            app.Button_10.Text = '开始诊断';
            app.EditField_5Label = uilabel(app.Panel_5);
            app.EditField_5Label.Text = '样本比';
            app.EditField_5 = uieditfield(app.Panel_5, 'numeric');
            app.EditField_5.Tooltip = {'训练样本与测试样本数量的比值'};
            app.EditField_5.Value = 0.8;
            app.CheckBox_2 = uicheckbox(app.Panel_5);
            app.CheckBox_2.Tooltip = {'把数据缩放到[0'; '1]'};
            app.CheckBox_2.Text = '归一化';
            app.CheckBox_2.Value = true;
            app.EditField_6Label = uilabel(app.Panel_5);
            app.EditField_6Label.Text = '算法';
            app.EditField_6 = uieditfield(app.Panel_5, 'text');
            app.EditField_6.Editable = 'off';
            app.EditField_6.Value = '支持向量机';
            app.EditField_7Label = uilabel(app.Panel_5);
            app.EditField_7Label.Text = '交叉验证次数';
            app.EditField_7 = uieditfield(app.Panel_5, 'numeric');
            app.EditField_7.Tooltip = {'训练样本与测试样本数量的比值'};
            app.EditField_7.Value = 5;
            app.EditField_8Label = uilabel(app.Panel_5);
            app.EditField_8Label.Text = '核函数';
            app.EditField_8 = uieditfield(app.Panel_5, 'text');
            app.EditField_8.Editable = 'off';
            app.EditField_8.Value = '高斯核函数';
            app.Panel_7 = uipanel(app.Panel_5);
            app.Panel_7.Title = '添加高斯白噪声';
            app.CheckBox_3 = uicheckbox(app.Panel_7);
            app.CheckBox_3.Text = '是否添加高斯白噪声';
            app.DropDown_5Label = uilabel(app.Panel_7);
            app.DropDown_5Label.Text = '高斯白噪声强度';
            app.DropDown_5 = uidropdown(app.Panel_7);
            app.DropDown_5.Items = {'添加1%', '添加2%', '添加3%', '添加4%', '添加5%'};
            app.DropDown_5.Value = '添加1%';
            app.TextArea_2Label = uilabel(app.Tab_6);
            app.TextArea_2Label.Text = '诊断结果';
            app.TextArea_2 = uitextarea(app.Tab_6);
            app.Tab_4 = uitab(app.TabGroup);
            app.Tab_4.Title = '航空发动机气路部件故障类别智能诊断模块';
            app.UIAxes2_3 = uiaxes(app.Tab_4);
            title(app.UIAxes2_3, '测试集的诊断示意图')
            xlabel(app.UIAxes2_3, '测试集样本')
            ylabel(app.UIAxes2_3, '类别标签')
            app.Panel_8 = uipanel(app.Tab_4);
            app.Panel_8.Tooltip = {''};
            app.Panel_8.Title = '航空发动机气路部件故障类别的故障诊断模型';
            app.Panel_9 = uipanel(app.Panel_8);
            app.Panel_9.Title = '智能优化算法参数设置';
            app.EditField_15Label = uilabel(app.Panel_9);
            app.EditField_15Label.Text = '进化迭代数';
            app.EditField_15 = uieditfield(app.Panel_9, 'numeric');
            app.EditField_15.Value = 50;
            app.EditField_16Label = uilabel(app.Panel_9);
            app.EditField_16Label.Text = '种群数量';
            app.EditField_16 = uieditfield(app.Panel_9, 'numeric');
            app.EditField_16.Value = 10;
            app.CEditField_2Label = uilabel(app.Panel_9);
            app.CEditField_2Label.Text = '参数C的范围';
            app.CEditField_2 = uieditfield(app.Panel_9, 'numeric');
            app.CEditField_2.Value = 0.01;
            app.EditField2_3 = uieditfield(app.Panel_9, 'numeric');
            app.EditField2_3.Value = 100;
            app.Label_3 = uilabel(app.Panel_9);
            app.Label_3.Text = '—';
            app.gEditField_3Label = uilabel(app.Panel_9);
            app.gEditField_3Label.Text = '参数g的范围';
            app.gEditField_3 = uieditfield(app.Panel_9, 'numeric');
            app.gEditField_3.Value = 0.01;
            app.EditField2_4 = uieditfield(app.Panel_9, 'numeric');
            app.EditField2_4.Value = 100;
            app.Label_4 = uilabel(app.Panel_9);
            app.Label_4.Text = '—';
            app.EditField_17Label = uilabel(app.Panel_9);
            app.EditField_17Label.Text = '算法';
            app.EditField_17 = uieditfield(app.Panel_9, 'text');
            app.EditField_17.Editable = 'off';
            app.EditField_17.Value = 'IGWO';
            app.Button_11 = uibutton(app.Panel_8, 'push');
            app.Button_11.ButtonPushedFcn = createCallbackFcn(app, @Button_11Pushed, true);
            app.Button_11.Text = '保留诊断模型';
            app.Button_12 = uibutton(app.Panel_8, 'push');
            app.Button_12.ButtonPushedFcn = createCallbackFcn(app, @Button_12Pushed, true);
            app.Button_12.Text = '开始诊断';
            app.EditField_11Label = uilabel(app.Panel_8);
            app.EditField_11Label.Text = '样本比';
            app.EditField_11 = uieditfield(app.Panel_8, 'numeric');
            app.EditField_11.Tooltip = {'训练样本与测试样本数量的比值'};
            app.EditField_11.Value = 0.8;
            app.CheckBox_4 = uicheckbox(app.Panel_8);
            app.CheckBox_4.Tooltip = {'把数据缩放到[0'; '1]'};
            app.CheckBox_4.Text = '归一化';
            app.CheckBox_4.Value = true;
            app.EditField_12Label = uilabel(app.Panel_8);
            app.EditField_12Label.Text = '算法';
            app.EditField_12 = uieditfield(app.Panel_8, 'text');
            app.EditField_12.Editable = 'off';
            app.EditField_12.Value = '核极限学习机';
            app.EditField_13Label = uilabel(app.Panel_8);
            app.EditField_13Label.Text = '交叉验证次数';
            app.EditField_13 = uieditfield(app.Panel_8, 'numeric');
            app.EditField_13.Tooltip = {'训练样本与测试样本数量的比值'};
            app.EditField_13.Value = 5;
            app.EditField_14Label = uilabel(app.Panel_8);
            app.EditField_14Label.Text = '核函数';
            app.EditField_14 = uieditfield(app.Panel_8, 'text');
            app.EditField_14.Editable = 'off';
            app.EditField_14.Value = '高斯核函数';
            app.Panel_10 = uipanel(app.Panel_8);
            app.Panel_10.Title = '添加高斯白噪声';
            app.CheckBox_5 = uicheckbox(app.Panel_10);
            app.CheckBox_5.Text = '是否添加高斯白噪声';
            app.DropDown_6Label = uilabel(app.Panel_10);
            app.DropDown_6Label.Text = '高斯白噪声强度';
            app.DropDown_6 = uidropdown(app.Panel_10);
            app.DropDown_6.Items = {'添加1%', '添加2%', '添加3%', '添加4%', '添加5%'};
            app.DropDown_6.Value = '添加1%';
            app.Panel_14 = uipanel(app.Panel_8);
            app.Panel_14.Title = '故障部件选择';
            app.Label_5 = uilabel(app.Panel_14);
            app.Label_5.Text = '故障部件';
            app.DropDown_7 = uidropdown(app.Panel_14);
            app.DropDown_7.Items = {'风扇', '压气机', '高压涡轮', '低压涡轮'};
            app.DropDown_7.ValueChangedFcn = createCallbackFcn(app, @DropDown_7ValueChanged, true);
            app.DropDown_7.Value = '风扇';
            app.TextArea_3Label = uilabel(app.Tab_4);
            app.TextArea_3Label.Text = '诊断结果';
            app.TextArea_3 = uitextarea(app.Tab_4);
            app.UIAxes_3 = uiaxes(app.Tab_4);
            title(app.UIAxes_3, '智能算法寻优示意图')
            xlabel(app.UIAxes_3, '迭代次数')
            ylabel(app.UIAxes_3, '最佳适应度值(%)')
            app.Tab_7 = uitab(app.TabGroup);
            app.Tab_7.Title = '案例测试';
            app.UITable_2 = uitable(app.Tab_7);
            app.UITable_2.ColumnName = {'高压转子转速nh(rpm)'; '风扇出口压力p21(100kpa)'; '压气机出口压力p3(100kpa)'; '高压涡轮出口压力p5（100kpa）'; '低压涡轮出口压力p55'; '高压涡轮出口温度t5(K)'; '低压涡轮出口温度t55'};
            app.UITable_2.RowName = {};
            app.UITable_2.ColumnSortable = true;
            app.UITable_2.ColumnEditable = true;
            app.Button_16 = uibutton(app.Tab_7, 'push');
            app.Button_16.ButtonPushedFcn = createCallbackFcn(app, @Button_16Pushed, true);
            app.Button_16.Text = '导入故障数据';
            app.Panel_11 = uipanel(app.Tab_7);
            app.Panel_11.Title = '故障判断';
            app.Panel_11.SizeChangedFcn = createCallbackFcn(app, @Panel_11SizeChanged, true);
            app.TextArea_4 = uitextarea(app.Panel_11);
            app.Button_17 = uibutton(app.Panel_11, 'state');
            app.Button_17.ValueChangedFcn = createCallbackFcn(app, @Button_17ValueChanged, true);
            app.Button_17.Text = '开始诊断';
            app.TextArea_5Label_2 = uilabel(app.Panel_11);
            app.TextArea_5Label_2.Text = '诊断结果';
            app.Panel_12 = uipanel(app.Tab_7);
            app.Panel_12.Title = '故障定位';
            app.TextArea_5Label = uilabel(app.Panel_12);
            app.TextArea_5Label.Text = '诊断结果';
            app.TextArea_5 = uitextarea(app.Panel_12);
            app.Button_18 = uibutton(app.Panel_12, 'state');
            app.Button_18.ValueChangedFcn = createCallbackFcn(app, @Button_18ValueChanged, true);
            app.Button_18.Text = '开始诊断';
            app.Panel_13 = uipanel(app.Tab_7);
            app.Panel_13.Title = '故障识别';
            app.TextArea_6Label = uilabel(app.Panel_13);
            app.TextArea_6Label.Text = '诊断结果';
            app.TextArea_6 = uitextarea(app.Panel_13);
            app.Button_19 = uibutton(app.Panel_13, 'state');
            app.Button_19.ValueChangedFcn = createCallbackFcn(app, @Button_19ValueChanged, true);
            app.Button_19.Text = '开始诊断';
            app.Button_20 = uibutton(app.Tab_7, 'push');
            app.Button_20.ButtonPushedFcn = createCallbackFcn(app, @Button_20Pushed, true);
            app.Button_20.Text = '保留诊断结果';
            app.EditField_18 = uieditfield(app.Tab_7, 'text');
            app.EditField_18.Value = '航空发动机故障诊断分析';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end